classdef arosStandaloneGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdaptiveResolutionOrientationSpaceUIFigure  matlab.ui.Figure
        BasicParametersPanel          matlab.ui.container.Panel
        SigmaSliderLabel              matlab.ui.control.Label
        SigmaSlider                   matlab.ui.control.Slider
        OrderKhSliderLabel            matlab.ui.control.Label
        OrderKhSlider                 matlab.ui.control.Slider
        ImageFileEditFieldLabel       matlab.ui.control.Label
        ImageFileEditField            matlab.ui.control.EditField
        BrowseforImageFileButton      matlab.ui.control.Button
        zSpinnerLabel                 matlab.ui.control.Label
        zSpinner                      matlab.ui.control.Spinner
        cSpinnerLabel                 matlab.ui.control.Label
        cSpinner                      matlab.ui.control.Spinner
        tSpinnerLabel                 matlab.ui.control.Label
        tSpinner                      matlab.ui.control.Spinner
        ROIandMaskingParametersPanel  matlab.ui.container.Panel
        MaskFileEditFieldLabel        matlab.ui.control.Label
        MaskFileEditField             matlab.ui.control.EditField
        BrowseforMaskButton           matlab.ui.control.Button
        MaskDilationDiskRadiusLabel   matlab.ui.control.Label
        MaskDilationDiskRadiusSlider  matlab.ui.control.Slider
        MaskTypeDropDownLabel         matlab.ui.control.Label
        MaskTypeDropDown              matlab.ui.control.DropDown
        FillHolesinMaskCheckBox       matlab.ui.control.CheckBox
        ThresholdingMethodDropDownLabel  matlab.ui.control.Label
        ThresholdingMethodDropDown    matlab.ui.control.DropDown
        PreviewMaskButton             matlab.ui.control.Button
        RunAnalysisButton             matlab.ui.control.Button
        DiagnosticModeCheckBox        matlab.ui.control.CheckBox
        AdaptiveResolutionOrientationSpaceLabel  matlab.ui.control.Label
        SaveAdvancedOutputCheckBox    matlab.ui.control.CheckBox
    end


    properties (Access = private)
        reader % ImageReader
        originalFig % Description
        useBioFormats % Description
    end

    methods (Access = private)
    
        function results = loadImage(app,filename)
            filename = strrep(filename,[pwd filesep],'');
            app.ImageFileEditField.Value = filename;
            app.ImageFileEditField.FontColor = 'k';
            app.RunAnalysisButton.Enable = 'on';
            app.PreviewMaskButton.Enable = 'on';
            app.RunAnalysisButton.BackgroundColor = [0 0.96 0];
            app.PreviewMaskButton.BackgroundColor = [0.96 0.96 0];

            
            if(app.useBioFormats)
            
                % Use Bio-Formats to get meta data and control spinners
            
                app.reader = bfGetReader(filename);
                sZ = app.reader.getSizeZ();
                sC = app.reader.getSizeC();
                sT = app.reader.getSizeT();
                if(sZ > 1)
                    app.zSpinner.Enable = 'on';
                    app.zSpinner.Limits = [1 sZ];
                else
                    app.zSpinner.Value = 1;
                    app.zSpinner.Enable = 'off';
                end
                if(sC > 1)
                    app.cSpinner.Enable = 'on';
                    sCmin = 1;
                    if(sC == 3)
                        sCmin = 0;
                    end
                    app.cSpinner.Limits = [sCmin sC];
                else
                    app.cSpinner.Value = 1;
                    app.cSpinner.Enable = 'off';
                end
                if(sT > 1)
                    app.tSpinner.Enable = 'on';
                    app.tSpinner.Limits = [1 sT];
                else
                    app.tSpinner.Value = 1;
                    app.tSpinner.Enable = 'off';
                end
                showOriginal(app);
            else
                app.reader = filename;
                showOriginal(app);
            end
        end
        
        function [hfig,I] = showOriginal(app)
            I = loadOriginalImageData(app);
            % Reuse a single figure if possible
            if(~isvalid(app.originalFig) || ~isa(app.originalFig,'matlab.ui.Figure'))
                app.originalFig = figure;
            end
            hfig = figure(app.originalFig);
            imshow(I,[],'Parent',gca);
        end
        
        function I = loadOriginalImageData(app)
            if(app.useBioFormats)
                if(app.cSpinner.Value > 0 || app.reader.getSizeC() ~= 3)
                    I = bfGetPlaneAtZCT(app.reader, ...
                            app.zSpinner.Value, ...
                            max(app.cSpinner.Value,1), ...
                            app.tSpinner.Value);
                else
                    % A channel spinner value of 0 will convert a color image to grayscale
                    I = arrayfun(@(c) bfGetPlaneAtZCT(app.reader, ...
                            app.zSpinner.Value, ...
                            c, ...
                            app.tSpinner.Value), ...
                            1:3,'UniformOutput',false);
                    I = cat(3,I{:});
                    I = rgb2gray(I);
                end
            else
                I = imread(app.reader);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.useBioFormats = bfCheckJavaPath(true);
            if(~app.useBioFormats)
                warning('arosStandaloneGUI:CannotUseBioformats','Bio-Formats could not be loaded. Default to builtin imread');
            else
                bfInitLogging();
            end
        end

        % Button pushed function: BrowseforImageFileButton
        function BrowseforImageFileButtonPushed(app, event)
            if(app.useBioFormats)
                [filename,pathname] = uigetfile(bfGetFileExtensions, 'Choose an image file to open');
            else
                [filename,pathname] = uigetfile({'*.tif;*.tiff;*.png'});
            end
            if(filename)
                filename = [pathname filename];
                loadImage(app,filename);
            end
        end

        % Button pushed function: BrowseforMaskButton
        function BrowseforMaskButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.tif;*.tiff;*.png'});
            if(filename)
                filename = [pathname filename];
                filename = strrep(filename,[pwd filesep],'');
                app.MaskFileEditField.Value = filename;
                app.MaskFileEditField.FontColor = 'k';
                app.MaskFileEditField.Enable = 'on';
                if(strcmp(app.MaskTypeDropDown.Value,'Automatic'))
                    app.MaskTypeDropDown.Value = 'ROI Mask';
                end
                app.PreviewMaskButton = 'on';
            end
        end

        % Value changed function: MaskTypeDropDown
        function MaskTypeDropDownValueChanged(app, event)
            value = app.MaskTypeDropDown.Value;
            switch(value)
                case 'Automatic'
                    app.MaskFileEditField.Value = '';
                    app.MaskFileEditField.Enable = 'off';
                    app.MaskDilationDiskRadiusSlider.Enable = 'on';
                    app.FillHolesinMaskCheckBox.Enable = 'on';
                    app.ThresholdingMethodDropDown.Enable = 'on';
                    if(app.reader)
                        app.PreviewMaskButton = 'on';
                    end
                case 'ROI Mask'
                    %app.MaskFileEditField.Value = '';
                    app.MaskFileEditField.Enable = 'on';
                    app.MaskDilationDiskRadiusSlider.Enable = 'on';
                    app.FillHolesinMaskCheckBox.Enable = 'on';
                    app.ThresholdingMethodDropDown.Enable = 'on';
                    app.PreviewMaskButton = 'off';
                case 'NLMS Mask'
                    %app.MaskFileEditField.Value = '';
                    app.MaskFileEditField.Enable = 'on';
                    app.MaskDilationDiskRadiusSlider.Enable = 'off';
                    app.FillHolesinMaskCheckBox.Enable = 'off';
                    app.ThresholdingMethodDropDown.Enable = 'off';
                    app.PreviewMaskButton = 'off';
                case 'No Mask'
                    app.MaskFileEditField.Value = '';
                    app.MaskFileEditField.Enable = 'off';
                    app.MaskDilationDiskRadiusSlider.Enable = 'off';
                    app.FillHolesinMaskCheckBox.Enable = 'off';
                    app.ThresholdingMethodDropDown.Enable = 'off';
                    app.PreviewMaskButton = 'off';
            end
        end

        % Button pushed function: PreviewMaskButton
        function PreviewMaskButtonPushed(app, event)
            close all;
            app.PreviewMaskButton.Enable = 'off';
            app.PreviewMaskButton.Text = 'Loading files ...';
            
            try
            
            % Load original image
            [hfig, I] = showOriginal(app);
            pos = hfig.Position;
            
            order = app.OrderKhSlider.Value;
            sigma = app.SigmaSlider.Value;
            
            args = {I, order, sigma};
            
            % Load mask if needed
            
            maskType = app.MaskTypeDropDown.Value;
            if(strcmp(maskType,'No Mask'))
                mask = true(size(I));
            elseif(~strcmp(maskType,'Automatic'))
                mask = imread(app.MaskFileEditField.Value);
                mask = mask(:,:,1) > 0;
                figure; imshow(mask,[]);
            end
            
            % Get masking and thresholding parameters
            
            if(any(strcmp(maskType,{'Automatic','ROI Mask'})))
                thresholdingMethod = app.ThresholdingMethodDropDown.Value;
                if(ischar(thresholdingMethod))
                    thresholdingMethod = str2func(thresholdingMethod);
                end
                maskDilationDiskRadius = round(app.MaskDilationDiskRadiusSlider.Value);
                maskFillHoles = app.FillHolesinMaskCheckBox.Value;
            end
            
            % Create masking and thresholding arguments
            
            switch(maskType)
                case 'Automatic'
                    args = [args ...
                        {'meanThresholdMethod',thresholdingMethod, ...
                         'maskDilationDiskRadius',maskDilationDiskRadius, ...
                         'maskFillHoles',maskFillHoles}];
                case 'ROI Mask'
                    args = [args ...
                        {'mask',mask, ...
                         'meanThresholdMethod',thresholdingMethod, ...
                         'maskDilationDiskRadius',maskDilationDiskRadius, ...
                         'maskFillHoles',maskFillHoles}];
                case 'NLMS Mask'
                    args = [args {'nlmsMask',mask}];
                case 'No Mask'
                    args = [args {'nlmsMask',mask}];
            end
            
            % Check for diagnostic mode
            
            diagnosticMode = app.DiagnosticModeCheckBox.Value;
            args = [args {'diagnosticMode',diagnosticMode}];
            
            args = [args {'maskOnly',true}];
                       
            % Run the analysis
            
            app.PreviewMaskButton.Text = 'Gen. Mask ...';
            
            drawnow;
            
            [nlmsMask] = steerableAdaptiveResolutionOrientationSpaceDetector(args{:});
            
            figure; imshow(nlmsMask,[]);
            
            catch err
                rethrow(err);
            end
            
            app.PreviewMaskButton.Enable = 'on';
            app.PreviewMaskButton.Text = 'Preview Mask';
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            close all;
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Text = 'Loading files ...';
            app.RunAnalysisButton.BackgroundColor = [0.96 0.96 0];
            
            try
            
            % Load original image
            [hfig, I] = showOriginal(app);
            pos = hfig.Position;
            
            order = app.OrderKhSlider.Value;
            sigma = app.SigmaSlider.Value;
            
            args = {I, order, sigma};
            
            % Load mask if needed
            
            maskType = app.MaskTypeDropDown.Value;
            if(strcmp(maskType,'No Mask'))
                mask = true(size(I));
            elseif(~strcmp(maskType,'Automatic'))
                mask = imread(app.MaskFileEditField.Value);
                mask = mask(:,:,1) > 0;
                figure; imshow(mask,[]);
            end
            
            % Get masking and thresholding parameters
            
            if(any(strcmp(maskType,{'Automatic','ROI Mask'})))
                thresholdingMethod = app.ThresholdingMethodDropDown.Value;
                if(ischar(thresholdingMethod))
                    thresholdingMethod = str2func(thresholdingMethod);
                end
                maskDilationDiskRadius = round(app.MaskDilationDiskRadiusSlider.Value);
                maskFillHoles = app.FillHolesinMaskCheckBox.Value;
            end
            
            % Create masking and thresholding arguments
            
            switch(maskType)
                case 'Automatic'
                    args = [args ...
                        {'meanThresholdMethod',thresholdingMethod, ...
                         'maskDilationDiskRadius',maskDilationDiskRadius, ...
                         'maskFillHoles',maskFillHoles}];
                case 'ROI Mask'
                    args = [args ...
                        {'mask',mask, ...
                         'meanThresholdMethod',thresholdingMethod, ...
                         'maskDilationDiskRadius',maskDilationDiskRadius, ...
                         'maskFillHoles',maskFillHoles}];
                case 'NLMS Mask'
                    args = [args {'nlmsMask',mask}];
                case 'No Mask'
                    args = [args {'nlmsMask',mask}];
            end
            
            % Check for diagnostic mode
            
            diagnosticMode = app.DiagnosticModeCheckBox.Value;
            args = [args {'diagnosticMode',diagnosticMode}];
            
            % Startup parallel pool
            
            app.RunAnalysisButton.Text = 'Par. pool starting ...';
            drawnow;
            
            pool = gcp;
            
            % Run the analysis
            
            app.RunAnalysisButton.Text = 'Running analysis ...';
            app.RunAnalysisButton.BackgroundColor = [0 0 0.96];
            app.RunAnalysisButton.FontColor = [1 1 1];
            
            drawnow;
            
            varsToSave = {'response','theta','nms'};
            if(app.SaveAdvancedOutputCheckBox.Value)
                % Obtain advanced outputs for further analysis or diagnostics
                [response, theta, nms, angularResponse, other] = steerableAdaptiveResolutionOrientationSpaceDetector(args{:});
                varsToSave = [varsToSave {'angularResponse','other'}];
            else
                % Only get three basic outputs
                [response, theta, nms] = steerableAdaptiveResolutionOrientationSpaceDetector(args{:});
            end
            
            % Save the analysis
                       
            app.RunAnalysisButton.Text = 'Saving analysis ...';
            app.RunAnalysisButton.BackgroundColor = [0.96 0 0.96];
            app.RunAnalysisButton.FontColor = [0 0 0];
            
            msgbox(['Output has been saved to ' pwd filesep 'AROS_output.mat'], ...
                    'Analysis complete');
            
            drawnow;
            
            save('AROS_output.mat','I','args',varsToSave{:});
            
            % Show the analysis
           
            app.RunAnalysisButton.Text = 'Showing analysis ...';
            
            drawnow;
            
            resmax = max(response,[],3);
            
            fig_offset = [40 -40];
            
            hfig = figure('Name','Mask');
            hfig.Position([1 2]) = pos([1 2]) + fig_offset;
            pos = hfig.Position;
            imshow(~isnan(resmax),[]);
            
            hfig = figure('Name','Max Response');
            hfig.Position([1 2]) = pos([1 2]) + fig_offset;
            pos = hfig.Position;
            imshow(resmax,[]);
            
            resmax(resmax < 0) = 0;
            resmax(isnan(resmax)) = 0;
            
            hfig = figure('Name','NMS-Like Segmentation');
            hfig.Position([1 2]) = pos([1 2]) + fig_offset;
            pos = hfig.Position;
            imshow(nms,[]);
            
            blend = orientationSpace.blendOrientationMap(theta,response,colorcet('CBC2'));
            
            resmaxgray = mat2gray(resmax);
            
            hfig = figure('Name','Orientation Color Blended Response');
            hfig.Position([1 2]) = pos([1 2]) + fig_offset;
            pos = hfig.Position;
            imshow(blend.*resmaxgray,[]);
            
            catch err
                app.RunAnalysisButton.Text = 'Run Analysis';
                app.RunAnalysisButton.Enable = 'on';
                rethrow(err);
            end
            
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.RunAnalysisButton.BackgroundColor = [0 0.96 0];
            app.RunAnalysisButton.FontColor = [0 0 0];
            app.RunAnalysisButton.Enable = 'on';
        end

        % Value changed function: SigmaSlider
        function SigmaSliderValueChanged(app, event)
            value = app.SigmaSlider.Value;
            
        end

        % Value changed function: cSpinner, tSpinner, zSpinner
        function zSpinnerValueChanged(app, event)
            showOriginal(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdaptiveResolutionOrientationSpaceUIFigure = uifigure;
            app.AdaptiveResolutionOrientationSpaceUIFigure.Name = 'Adaptive Resolution Orientation Space';
            app.BasicParametersPanel = uipanel(app.AdaptiveResolutionOrientationSpaceUIFigure);
            app.BasicParametersPanel.Title = 'Basic Parameters';
            app.SigmaSliderLabel = uilabel(app.BasicParametersPanel);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.BasicParametersPanel);
            app.SigmaSlider.Limits = [0 10];
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @SigmaSliderValueChanged, true);
            app.SigmaSlider.MinorTicks = [];
            app.SigmaSlider.Value = 2;
            app.OrderKhSliderLabel = uilabel(app.BasicParametersPanel);
            app.OrderKhSliderLabel.Text = 'Order (Kh)';
            app.OrderKhSlider = uislider(app.BasicParametersPanel);
            app.OrderKhSlider.Limits = [1 12];
            app.OrderKhSlider.Value = 8;
            app.ImageFileEditFieldLabel = uilabel(app.BasicParametersPanel);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.ImageFileEditField = uieditfield(app.BasicParametersPanel, 'text');
            app.ImageFileEditField.Editable = 'off';
            app.ImageFileEditField.Value = '<enter filename>';
            app.BrowseforImageFileButton = uibutton(app.BasicParametersPanel, 'push');
            app.BrowseforImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforImageFileButtonPushed, true);
            app.BrowseforImageFileButton.Text = 'Browse for Image File ...';
            app.zSpinnerLabel = uilabel(app.BasicParametersPanel);
            app.zSpinnerLabel.Enable = 'off';
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.BasicParametersPanel);
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.zSpinner.Limits = [1 Inf];
            app.zSpinner.Enable = 'off';
            app.zSpinner.Value = 1;
            app.cSpinnerLabel = uilabel(app.BasicParametersPanel);
            app.cSpinnerLabel.Enable = 'off';
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.BasicParametersPanel);
            app.cSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.cSpinner.Limits = [0 Inf];
            app.cSpinner.Enable = 'off';
            app.cSpinner.Value = 1;
            app.tSpinnerLabel = uilabel(app.BasicParametersPanel);
            app.tSpinnerLabel.Enable = 'off';
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.BasicParametersPanel);
            app.tSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.tSpinner.Limits = [1 Inf];
            app.tSpinner.Enable = 'off';
            app.tSpinner.Value = 1;
            app.ROIandMaskingParametersPanel = uipanel(app.AdaptiveResolutionOrientationSpaceUIFigure);
            app.ROIandMaskingParametersPanel.Title = 'ROI and Masking Parameters';
            app.MaskFileEditFieldLabel = uilabel(app.ROIandMaskingParametersPanel);
            app.MaskFileEditFieldLabel.Enable = 'off';
            app.MaskFileEditFieldLabel.Text = 'Mask File';
            app.MaskFileEditField = uieditfield(app.ROIandMaskingParametersPanel, 'text');
            app.MaskFileEditField.Editable = 'off';
            app.MaskFileEditField.Enable = 'off';
            app.MaskFileEditField.Value = '<automatic>';
            app.BrowseforMaskButton = uibutton(app.ROIandMaskingParametersPanel, 'push');
            app.BrowseforMaskButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforMaskButtonPushed, true);
            app.BrowseforMaskButton.Text = 'Browse for Mask ...';
            app.MaskDilationDiskRadiusLabel = uilabel(app.ROIandMaskingParametersPanel);
            app.MaskDilationDiskRadiusLabel.Text = {'Mask Dilation'; 'Disk Radius'};
            app.MaskDilationDiskRadiusSlider = uislider(app.ROIandMaskingParametersPanel);
            app.MaskDilationDiskRadiusSlider.Limits = [0 10];
            app.MaskDilationDiskRadiusSlider.MinorTicks = [];
            app.MaskDilationDiskRadiusSlider.Value = 3;
            app.MaskTypeDropDownLabel = uilabel(app.ROIandMaskingParametersPanel);
            app.MaskTypeDropDownLabel.Text = 'Mask Type';
            app.MaskTypeDropDown = uidropdown(app.ROIandMaskingParametersPanel);
            app.MaskTypeDropDown.Items = {'Automatic', 'ROI Mask', 'NLMS Mask', 'No Mask'};
            app.MaskTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskTypeDropDownValueChanged, true);
            app.MaskTypeDropDown.Value = 'Automatic';
            app.FillHolesinMaskCheckBox = uicheckbox(app.ROIandMaskingParametersPanel);
            app.FillHolesinMaskCheckBox.Text = 'Fill Holes in Mask';
            app.ThresholdingMethodDropDownLabel = uilabel(app.ROIandMaskingParametersPanel);
            app.ThresholdingMethodDropDownLabel.Text = 'Thresholding Method';
            app.ThresholdingMethodDropDown = uidropdown(app.ROIandMaskingParametersPanel);
            app.ThresholdingMethodDropDown.Items = {'Otsu (bimodal)', 'Rosin (unimodal)'};
            app.ThresholdingMethodDropDown.ItemsData = {'@thresholdOtsu', '@thresholdRosin'};
            app.ThresholdingMethodDropDown.Editable = 'on';
            app.ThresholdingMethodDropDown.Value = '@thresholdOtsu';
            app.PreviewMaskButton = uibutton(app.ROIandMaskingParametersPanel, 'push');
            app.PreviewMaskButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewMaskButtonPushed, true);
            app.PreviewMaskButton.Enable = 'off';
            app.PreviewMaskButton.Text = 'Preview Mask';
            app.RunAnalysisButton = uibutton(app.AdaptiveResolutionOrientationSpaceUIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.DiagnosticModeCheckBox = uicheckbox(app.AdaptiveResolutionOrientationSpaceUIFigure);
            app.DiagnosticModeCheckBox.Text = 'Diagnostic Mode';
            app.AdaptiveResolutionOrientationSpaceLabel = uilabel(app.AdaptiveResolutionOrientationSpaceUIFigure);
            app.AdaptiveResolutionOrientationSpaceLabel.Text = 'Adaptive Resolution Orientation Space';
            app.SaveAdvancedOutputCheckBox = uicheckbox(app.AdaptiveResolutionOrientationSpaceUIFigure);
            app.SaveAdvancedOutputCheckBox.Text = 'Save Advanced Output';
        end
    end

    methods (Access = public)

        % Construct app
        function app = arosStandaloneGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdaptiveResolutionOrientationSpaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdaptiveResolutionOrientationSpaceUIFigure)
        end
    end
end